classdef BeamProfiler2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BeamprofilerUIFigure       matlab.ui.Figure
        SerialConnectionPanel      matlab.ui.container.Panel
        ConnectButton              matlab.ui.control.Button
        CloseConnectionButton      matlab.ui.control.Button
        PortDropDownLabel          matlab.ui.control.Label
        PortDropDown               matlab.ui.control.DropDown
        PortlistRefreshButton      matlab.ui.control.Button
        ImageViewPanel             matlab.ui.container.Panel
        GreyscaleImage             matlab.ui.control.UIAxes
        FalseColorImage            matlab.ui.control.UIAxes
        MovementPanel              matlab.ui.container.Panel
        PositionmmEditFieldLabel   matlab.ui.control.Label
        PositionmmEditField        matlab.ui.control.NumericEditField
        TypeofMovementButtonGroup  matlab.ui.container.ButtonGroup
        absoluteButton             matlab.ui.control.RadioButton
        relativeButton             matlab.ui.control.RadioButton
        MOVEButton                 matlab.ui.control.Button
        ReferenceRunButton         matlab.ui.control.Button
        referencedLampLabel        matlab.ui.control.Label
        referencedLamp             matlab.ui.control.Lamp
        PosGauge                   matlab.ui.control.LinearGauge
        PosReadout                 matlab.ui.control.NumericEditField
        CameraControlPanel         matlab.ui.container.Panel
        LiveViewButton             matlab.ui.control.StateButton
        CloseCameraButton          matlab.ui.control.Button
        TakeImageButton            matlab.ui.control.Button
        exposuretimemsSliderLabel  matlab.ui.control.Label
        exposuretimemsSlider       matlab.ui.control.Slider
        framerateSliderLabel       matlab.ui.control.Label
        framerateSlider            matlab.ui.control.Slider
        gainSliderLabel            matlab.ui.control.Label
        gainSlider                 matlab.ui.control.Slider
        ProfileView                matlab.ui.container.Panel
        Profile1                   matlab.ui.control.UIAxes
        Profile2                   matlab.ui.control.UIAxes
        w0_xLabel                  matlab.ui.control.Label
        w1EditField                matlab.ui.control.NumericEditField
        w0_yLabel                  matlab.ui.control.Label
        w2EditField                matlab.ui.control.NumericEditField
        showfitCheckBox            matlab.ui.control.CheckBox
        PerformGaussianFitButton   matlab.ui.control.Button
        CameraPositionLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        serPort % serial port for communication with arduino
        serialInputString % serial input buffer for strings
        serialInputInt % serial input buffer for position integers
        errorHandle % handle for error dialog windows
        
        axisReferenced % flag if axis is referenced
        axisPosition % position of camera in mm
        axisDirection % current direction of axis movement
        
        himg % matlab figure handle of camera image
        img % image data handle
        cam % camera handle
        pixelSize % pixel size of camera
        gain % digital gain value
        
        estimatedParameters
        estGauss
    end
    
    methods (Access = private)
        
        function success = establishSerialConnection(app)
            port = app.PortDropDown.Value;
            try
                app.serPort = serialport(port, 9600);
                configureCallback(app.serPort, "terminator", @app.serialCallbackFunction);
                success = true;
            catch ME
                app.errorHandle = errordlg("Establishing the serial connection to the" + ...
                    " arduino failed." + newline +"Try to pull the " +...
                    "usb cable and plug it back in." + newline + ...
                    newline + ME.message);
                success = false;
            end
        end
        
        function serialCallbackFunction(app, src, ~)
            data = readline(src);
            % app.SerialMonitor.Value = app.SerialMonitor.Value + data + newline;
            pos = str2double(data);
            if isnan(pos)
                switch data.strip()
                    case "s"
                        app.animateGauge();
                    case "Referenced"
                        app.referencedLamp.Enable = 1;
                        app.referencedLamp.Color = [0 1 0];
                        app.axisReferenced = 1;
                        app.relativeButton.Enable = 1;
                    case "LowEndSwitch"
                    case "highEnd"
                    case "NotYetRef"
                    case "BadCmd"
                end
            else
                app.axisPosition = pos;
                app.PosReadout.Value = pos/100; 
                app.PosGauge.Value = pos/100;
                app.MOVEButton.Enable = 1;
                app.ReferenceRunButton.Enable = 1;
            end
        end
        
        function animateGauge(app)
                app.PosGauge.Value = app.PosGauge.Value + app.axisDirection*0.1;
                app.PosReadout.Value = app.PosReadout.Value + app.axisDirection*0.1;
        end
        
        function initCamera(app)
            asm = System.AppDomain.CurrentDomain.GetAssemblies;
            if ~any(arrayfun(@(n) strncmpi(char(asm.Get(n-1).FullName), ...
                    'uEyeDotNet', length('uEyeDotNet')), 1:asm.Length))
                NET.addAssembly(...
                    'C:\Program Files\IDS\uEye\Develop\DotNet\signed\uEyeDotNet.dll');
            end
            %   Create camera object handle
            app.cam = uEye.Camera;
            %   Open 1st available camera
            %   Returns if unSuccessful
            if ~strcmp(char(app.cam.Init), 'Success')
                error('Could not initialize camera');
            end
            %   Set display mode to bitmap (DiB)
            if ~strcmp(char(app.cam.Display.Mode.Set(uEye.Defines.DisplayMode.DiB)), ...
                    'Success')
                error('Could not set display mode');
            end
            %   Set colormode to 8-bit RAW
            if ~strcmp(char(app.cam.PixelFormat.Set(uEye.Defines.ColorMode.SensorRaw8)), ...
                    'Success')
                error('Could not set pixel format');
            end
            %   Set trigger mode to software (single image acquisition)
%             if ~strcmp(char(app.cam.Trigger.Set(uEye.Defines.TriggerMode.Software)), 'Success')
%                 error('Could not set trigger format');
%             end
             %   Allocate image memory
            [ErrChk, app.img.ID] = app.cam.Memory.Allocate(true);
            if ~strcmp(char(ErrChk), 'Success')
                error('Could not allocate memory');
            end
            %   Obtain image information
            % [ErrChk, app.img.Width, app.img.Height] = app.cam.Memory.GetSize(app.img.ID);
            [ErrChk, app.img.Width, app.img.Height, app.img.Bits, app.img.Pitch] ...
                = app.cam.Memory.Inquire(app.img.ID);
            if ~strcmp(char(ErrChk), 'Success')
                error('Could not get image information');
            end
        end
        
        function image = acquireImage(app)
        %   Acquire image
            if ~strcmp(char(app.cam.Acquisition.Freeze(true)), 'Success')
                error('Could not acquire image');
            end
            
            %   Extract image
            [ErrChk, image] = app.cam.Memory.CopyToArray(app.img.ID);
            if ~strcmp(char(ErrChk), 'Success')
                error('Could not obtain image data');
            end
        end
        
        function res = gauss2DcostFcn(~, params, xx, yy, zz)
            A = params(1);
            x0 = params(2);
            y0 = params(3);
            w1 = params(4);
            w2 = params(5);
            phi = params(6);
            A0 = params(7);

            exp1 = ((xx-x0)*cos(phi) + (yy-y0)*sin(phi)).^2;
            exp2 = ((yy-y0)*cos(phi) - (xx-x0)*sin(phi)).^2;

            gauss2D = A * exp(-2 * exp1 / w1^2) .* exp(-2 * exp2 / w2^2) + A0;
            res = zz - gauss2D;
            
        end
        
        function gauss2D = gauss2D(~, params, xx, yy)
            A = params(1);
            x0 = params(2);
            y0 = params(3);
            w1 = params(4);
            w2 = params(5);
            phi = params(6);
            A0 = params(7);

            exp1 = ((xx-x0)*cos(phi) + (yy-y0)*sin(phi)).^2;
            exp2 = ((yy-y0)*cos(phi) - (xx-x0)*sin(phi)).^2;

            gauss2D = A * exp(-2 * exp1 / w1^2) .* exp(-2 * exp2 / w2^2) + A0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PortDropDown.Items = serialportlist;
            app.axisPosition = 0.;
            app.axisReferenced = 0;
            app.PosGauge.MajorTicks = 0:10:300;
            app.PosGauge.MajorTickLabels = string(app.PosGauge.MajorTicks);
                        
            app.pixelSize = 5.2;
            drawnow();
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.ConnectButton.Enable = 0;
            app.ConnectButton.Text = "Connecting...";
            app.ConnectButton.BackgroundColor = [1.00 1.00 0.07];
            if ~app.establishSerialConnection
                app.ConnectButton.Text = "Error";
                app.ConnectButton.BackgroundColor = [1.0 0 0];
                uiwait(app.errorHandle);
                app.ConnectButton.Text = "Connect";
                app.ConnectButton.BackgroundColor = [0.96 0.96 0.96];
                app.ConnectButton.Enable = 1;
            else
                app.ConnectButton.Text = "Connected";
                app.ConnectButton.BackgroundColor = [0 1.00 0];
                app.CloseConnectionButton.Enable = 1;
                app.ReferenceRunButton.Enable = 1;
                app.MOVEButton.Enable = 1;
            end
        end

        % Button pushed function: CloseConnectionButton
        function CloseConnectionButtonPushed(app, event)
            app.serPort = [];
            app.CloseConnectionButton.Enable = 0;
            app.ConnectButton.Enable = 1;
            app.ConnectButton.Text = "Connect";
            app.ConnectButton.BackgroundColor = [0.96 0.96 0.96];
            app.PortDropDown.Items = serialportlist;
            app.ReferenceRunButton.Enable = 0;
            app.referencedLamp.Color = [0.94,0.94,0.94];
            app.MOVEButton.Enable = 0;
        end

        % Button pushed function: PortlistRefreshButton
        function PortlistRefreshButtonPushed(app, event)
            app.PortDropDown.Items = serialportlist;
        end

        % Button pushed function: ReferenceRunButton
        function ReferenceRunButtonPushed(app, event)
            selection = 'OK';
            if app.axisReferenced==1
                fig = uifigure;
                selection = uiconfirm(fig, 'Axis is already referenced. Do you really want to reference again?', ...
                    'Confirm Reference Run', 'Icon','warning');
                close(fig);
            end
            switch selection
                case 'OK'
                    app.PosGauge.Value = 0;
                    app.PosReadout.Value = 0;
                    desiredPosition = round(app.PositionmmEditField.Value, 2);
                    steps = desiredPosition * 100;
                    app.axisDirection = 1;
                    %app.MOVEButton.Enable = 0;
                    %app.ReferenceRunButton.Enable = 0;
                    stringToSend = sprintf("R;M;%i;1;", steps);
                    app.serPort.write(stringToSend, 'char');
                case 'Cancel'
                    return
            end
        end

        % Button pushed function: MOVEButton
        function MOVEButtonPushed(app, event)
            if app.axisReferenced==0
                return
            end
            desiredPos = round(app.PositionmmEditField.Value, 2);
            if app.absoluteButton.Value==1
                currentPos = app.axisPosition;
            elseif app.relativeButton.Value==1
                currentPos = 0;
            end
            deltaSteps = desiredPos*100 - currentPos;
            steps = abs(deltaSteps);
            app.axisDirection = sign(deltaSteps);
            
            %app.ReferenceRunButton.Enable = 0;
            %app.MOVEButton.Enable = 0;
            
            stringToSend = sprintf("M;%i;%i;", steps, app.axisDirection);
            app.serPort.write(stringToSend, 'char');
        end

        % Selection changed function: TypeofMovementButtonGroup
        function TypeofMovementButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypeofMovementButtonGroup.SelectedObject;
            if selectedButton.Text=="relative"
                app.PositionmmEditField.Limits = [-290 290];
            elseif selectedButton.Text=="absolute"
                app.PositionmmEditField.Limits = [0 290];
            end
        end

        % Value changed function: LiveViewButton
        function LiveViewButtonValueChanged(app, event)
            value = app.LiveViewButton.Value;
            
            if value==1
                app.initCamera;
                cmap = jet(256);
                cmap(251:end, :) = zeros(6, 3);
                app.himg = imagesc;
                himgAxes = app.himg.Parent;
                himgFig = himgAxes.Parent;
                himgFig.NumberTitle = 'off';
                himgFig.Name = "Live Camera View";
                himgFig.Position = [1053 532 629 434];
                axis(himgAxes, 'image');
                axis(himgAxes, 'tight');
                axis(himgAxes, [-inf inf -inf inf -inf inf 0 255]);
                colormap(himgAxes, cmap);
                colorbar(himgAxes);
                hx = line(0, 0, 'Color', 'r', 'LineWidth', 0.5, 'LineStyle', ':');
                hy = line(0, 0, 'Color', 'r', 'LineWidth', 0.5, 'LineStyle', ':');

                app.cam.Acquisition.Capture;
                app.exposuretimemsSlider.Enable = 1;
                app.framerateSlider.Enable = 1;
                app.gainSlider.Enable = 1;
                
%                 [~, val] = app.cam.Timing.Framerate.GetFrameRateRange;
%                 app.framerateSlider.Limits = [val.Minimum val.Maximum];
                app.cam.Timing.Framerate.Set(app.framerateSlider.Value);
                
                [~, val] = app.cam.Timing.Exposure.GetRange;
                app.exposuretimemsSlider.Limits = [val.Minimum val.Maximum];
                app.cam.Timing.Exposure.Set(app.exposuretimemsSlider.Value);
                
                app.gain = app.gainSlider.Value;
                %app.cam.Gain.Hardware.Factor.SetMaster(app.gainSlider.Value*100);

                app.CloseCameraButton.Enable = 1;
                app.TakeImageButton.Enable = 1;
                
                T = zeros(10, 1);
                tic
                while app.LiveViewButton.Value==1 && ishghandle(app.himg)
                    % Copy image from graphics card to RAM (wait for completion)
                    app.cam.DirectRenderer.StealNextFrame(uEye.Defines.DeviceParameter.Wait);
                    
                    % Copy image from RAM to Matlab array
                    [~, I] = app.cam.Memory.CopyToArray(app.img.ID);
                    app.img.Data = reshape(uint8(I), app.img.Width, app.img.Height).';
                    app.img.Data = app.gain*app.img.Data;
                    [M, ~] = max(app.img.Data(:));
                    %[I_row, I_col] = ind2sub(size(app.img.Data),I);
                    
                    % Calculate marginals
                    Ix = sum(uint64(app.img.Data));
                    Ix = Ix*.2*diff(app.himg.YData)/max(Ix);
                    Iy = sum(uint64(app.img.Data), 2);
                    Iy = Iy*.2*diff(app.himg.XData)/max(Iy);
                    [~, I_col] = max(Ix);
                    [~, I_row] = max(Iy);
                    
                    % Plot data
                    hx.XData = [1 1280];
                    hx.YData = [I_row I_row];
                    hy.XData = [I_col I_col];
                    hy.YData = [1 1024];
                    
%                     hx.XData = 1:length(Ix);
%                     hx.YData = Ix*.2*diff(app.himg.YData)/max(Ix);
%                     hy.YData = 1:length(Iy);
%                     hy.XData = Iy*.2*diff(app.himg.XData)/max(Iy);

                    app.himg.CData = app.img.Data;

                    T = [T(2:end); toc];
                    title(sprintf('FPS: %.1f ;  I_{max} = %i ;  Max. pos. = (%i, %i)', ...
                        10/diff(T([1 end])), M, I_col, I_row));
                    drawnow;
                end
                app.CloseCameraButton.Enable = 0;
                app.LiveViewButton.Value = 0;
                % Stop capture
                app.cam.Acquisition.Stop;

                % Free image memory
                app.cam.Memory.Free(app.img.ID);

                if ~strcmp(char(app.cam.Exit), 'Success')   % close camera properly
                        error('Could not close camera');
                end
            end
        end

        % Button pushed function: CloseCameraButton
        function CloseCameraButtonPushed(app, event)
            if ~strcmp(char(app.cam.Exit), 'Success')
                    error('Could not close camera');
            end
        end

        % Button pushed function: TakeImageButton
        function TakeImageButtonPushed(app, event)
            app.LiveViewButton.Value = 0;
            currentFrame = app.himg;
            XLims = round(currentFrame.Parent.XLim);
            YLims = round(currentFrame.Parent.YLim);
%             if any(isinf(XLims)) || any(isinf(YLims))
%                 ixy = currentFrame.CData;
%             else
%                 ixy = currentFrame.CData(YLims(1):YLims(2), XLims(1):XLims(2));
%             end
            ixy = app.img.Data;
            tmpImg = imshow(ixy, 'Parent', app.GreyscaleImage);
            set(tmpImg.Parent, 'YDir', 'normal');
            axis(tmpImg.Parent, 'image');
            axis(tmpImg.Parent, 'tight');
            xlim(tmpImg.Parent, XLims);
            ylim(tmpImg.Parent, YLims);
            
            imgDims=size(ixy);
            xPxls=1:imgDims(2);
            yPxls=1:imgDims(1);
            [app.img.X,app.img.Y] = meshgrid(xPxls*app.pixelSize,yPxls*app.pixelSize);
            
            tmpImg = imagesc(app.FalseColorImage, app.img.X(1,:), app.img.Y(:,1), ixy);
            set(tmpImg.Parent, 'YDir', 'normal');
            cmap = jet(256);
            cmap(251:end, :) = zeros(6, 3);
            axis(tmpImg.Parent, 'image');
            axis(tmpImg.Parent, 'tight');
            axis(tmpImg.Parent, [-inf inf -inf inf -inf inf 0 255]);
            colormap(tmpImg.Parent, cmap);
            colorbar(tmpImg.Parent);
            xlim(tmpImg.Parent, XLims*app.pixelSize);
            ylim(tmpImg.Parent, YLims*app.pixelSize);
            
%             xProfile = mean(ixy, 1);
%             yProfile = mean(ixy, 2);
%             [~, x0_ini] = max(xProfile);
%             [~, y0_ini] = max(yProfile);
%             app.xmEditField.Value = x0_ini*app.pixelSize;
%             app.ymEditField.Value = y0_ini*app.pixelSize;
            
            app.PerformGaussianFitButton.Enable = 1;
        end

        % Button pushed function: PerformGaussianFitButton
        function PerformGaussianFitButtonPushed(app, event)
            app.LiveViewButton.Value = 0;
            
            cla(app.Profile1);
            cla(app.Profile2);

            fig = uifigure;
            d = uiprogressdlg(fig, 'Title', 'Please wait',...
                'Message', 'performing gaussian fit');
            drawnow;
            % Defining X, Y and Z arrays
%             X = app.img.X;
%             Y = app.img.Y;
%             Z = double(app.img.Data);
            X = app.FalseColorImage.XLim;
            Y = app.FalseColorImage.YLim;
            x = X/app.pixelSize; y = Y/app.pixelSize;
            Z = double(app.FalseColorImage.Children(end).CData);
            if any(isinf(X)) || any(isinf(Y))
                LimIsInf = 1;
                ZClip = Z;
                zDim = size(ZClip);
                xPxls = 1:zDim(2); yPxls = 1:zDim(1);
                [Xfit,Yfit] = meshgrid(xPxls*app.pixelSize,yPxls*app.pixelSize);
            else
                LimIsInf = 0;
                ZClip = Z(y(1):y(2), x(1):x(2));
                zDim = size(ZClip);
                xPxls = X(1):app.pixelSize:X(2);
                yPxls = Y(1):app.pixelSize:Y(2);
                [Xfit,Yfit] = meshgrid(xPxls,yPxls);
            end

            d.Value = 0.1;
            d.Message = 'loading data';
            %% Initial guesses for fit parameters
            xProfile = mean(ZClip, 1);
            yProfile = mean(ZClip, 2);
            [~, x0_ini] = max(xProfile);
            [~, y0_ini] = max(yProfile);
            A0_ini = max(ZClip, [], 'all')*0.8;
            d.Value = 0.3;
            d.Message = 'defining initial fitting parameters and parameter bounds';
            initialGuess = [A0_ini, x0_ini*app.pixelSize, y0_ini*app.pixelSize, 500, 500, 0, 0];

            %% Define lower and upper bounds ANGLE IS FIXED TO ZERO FOR NOW !!!
            if LimIsInf
                lb = [0, 0, 0, 1, 1, 0, 0];
                ub = [255, 7000, 7000, 6000, 6000, 0, 255];
            else
                lb = [0, X(1), Y(1), 1, 1, 0, 0];
                ub = [255, X(2), Y(2), 6000, 6000, 0, 255];
            end
            %% Fitting
            d.Indeterminate = 1;
            d.Message = 'performing gaussian fit ...';
            app.estimatedParameters = lsqnonlin(@(params)app.gauss2DcostFcn(params,Xfit,Yfit,ZClip), initialGuess, lb, ub);
            
            % estParsInt = uint16(estimatedParameters);
            [Xest, Yest] = meshgrid(linspace(Xfit(1,1), Xfit(1,end), 1000), linspace(Yfit(1,1), Yfit(end, 1), 1000));
            app.estGauss = app.gauss2D(app.estimatedParameters, Xest, Yest);
            d.Indeterminate = 0;
            d.Value = 0.8;
            d.Message = 'calculating estimated 2D gauss';
            %% Calculate profiles along main axes
            x0 = app.estimatedParameters(2);
%             x0pxls = x0/app.pixelSize;
            x0pxls = (x0-Xfit(1,1))/app.pixelSize+1;
            y0 = app.estimatedParameters(3);
%             y0pxls = y0/app.pixelSize;
            y0pxls = (y0-Yfit(1,1))/app.pixelSize+1;
            px = improfile(ZClip, [1 zDim(2)], [y0pxls y0pxls]);
            py = improfile(ZClip, [x0pxls x0pxls], [1 zDim(1)]);
            
            d.Value = 0.9;
            d.Message = 'plotting of profiles';
            %% Show profile lines in false color image
            line(app.FalseColorImage, [1 1280]*app.pixelSize, [y0 y0], ...
                'Color', [1 0 0 0.8], 'LineWidth', 1);
            line(app.FalseColorImage, [x0 x0], [1 1024]*app.pixelSize, ...
                'Color', [1 0 0 0.8], 'LineWidth', 1);
            
            Iw0 = app.estimatedParameters(1)*0.135;
            
            %% Plot profiles with fits and display corresponding widths
            y0est = (y0-Yest(1,1))/(Yest(2,1)-Yest(1,1))+1;
            p1 = line(app.Profile1, [Xfit(1,1) Xfit(1,end)], [Iw0 Iw0], 'DisplayName', 'I_{max}/e^2');
            p2 = line(app.Profile1, Xfit(1,:), px, 'DisplayName', 'camera data', ...
                'LineStyle', "none", "Marker", 'o', 'Markersize', 6, 'MarkerFaceColor', 'b');
            p3 = line(app.Profile1, Xest(1,:), app.estGauss(uint16(y0est), :), ...
                'Color', 'r', 'DisplayName', 'gaussian fit', 'LineWidth', 2);
            xlim(app.Profile1, X);
            legend(app.Profile1, [p2 p3]);


            x0est = (x0-Xest(1,1))/(Xest(1,2)-Xest(1,1))+1;
            p1 = line(app.Profile2, [Yfit(1,1) Yfit(end,1)], [Iw0 Iw0]);
            p2 = line(app.Profile2, Yfit(:,1), py, 'DisplayName', 'camera data', ...
                'LineStyle', 'none', 'Marker', 'o', 'Markersize', 6, 'MarkerFaceColor', 'b');
            p3 = line(app.Profile2, Yest(:,1), app.estGauss(:, uint16(x0est)), ...
                'Color', 'r', 'DisplayName', 'gaussian fit', 'LineWidth', 2);
            xlim(app.Profile2, Y);
            legend(app.Profile2, [p2 p3]);
            d.Value = 1;
            d.Message = 'finished';
            app.w1EditField.Value = app.estimatedParameters(4);
            app.w2EditField.Value = app.estimatedParameters(5);
            
            close(d);
            close(fig);
        end

        % Value changed function: exposuretimemsSlider
        function exposuretimemsSliderValueChanged(app, event)
            value = app.exposuretimemsSlider.Value;
            app.cam.Timing.Exposure.Set(value);
        end

        % Value changed function: framerateSlider
        function framerateSliderValueChanged(app, event)
            value = app.framerateSlider.Value;
            app.cam.Timing.Framerate.Set(value);
            
            [~, val] = app.cam.Timing.Exposure.GetRange;
            app.exposuretimemsSlider.Limits = [val.Minimum val.Maximum];
%             [~, val] = app.cam.Timing.Exposure.Get;
%             app.exposuretimemsSlider.Value = val;
        end

        % Value changed function: gainSlider
        function gainSliderValueChanged(app, event)
            value = app.gainSlider.Value;
            app.gain = value;
            %app.cam.Gain.Hardware.Factor.SetMaster(value*100);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BeamprofilerUIFigure = uifigure('Visible', 'off');
            app.BeamprofilerUIFigure.Name = 'Beamprofiler';
            app.SerialConnectionPanel = uipanel(app.BeamprofilerUIFigure);
            app.SerialConnectionPanel.Title = 'Serial Connection';
            app.ConnectButton = uibutton(app.SerialConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.CloseConnectionButton = uibutton(app.SerialConnectionPanel, 'push');
            app.CloseConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @CloseConnectionButtonPushed, true);
            app.CloseConnectionButton.Enable = 'off';
            app.CloseConnectionButton.Text = 'Close Connection';
            app.PortDropDownLabel = uilabel(app.SerialConnectionPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.SerialConnectionPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.Value = {};
            app.PortlistRefreshButton = uibutton(app.SerialConnectionPanel, 'push');
            app.PortlistRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @PortlistRefreshButtonPushed, true);
            app.PortlistRefreshButton.Icon = 'refresh.png';
            app.PortlistRefreshButton.Text = '';
            app.ImageViewPanel = uipanel(app.BeamprofilerUIFigure);
            app.ImageViewPanel.Title = 'Image View';
            app.GreyscaleImage = uiaxes(app.ImageViewPanel);
            title(app.GreyscaleImage, 'Greyscale Image')
            xlabel(app.GreyscaleImage, '')
            ylabel(app.GreyscaleImage, '')
            app.GreyscaleImage.XLim = [0 1280];
            app.GreyscaleImage.YLim = [0 1024];
            app.GreyscaleImage.ZLim = [0 255];
            app.FalseColorImage = uiaxes(app.ImageViewPanel);
            title(app.FalseColorImage, 'False Color Image')
            xlabel(app.FalseColorImage, 'x / \mum')
            ylabel(app.FalseColorImage, 'y / \mum')
            app.FalseColorImage.XLim = [0 6656];
            app.FalseColorImage.YLim = [0 5325];
            app.FalseColorImage.ZLim = [0 255];
            app.MovementPanel = uipanel(app.BeamprofilerUIFigure);
            app.MovementPanel.Title = 'Movement';
            app.PositionmmEditFieldLabel = uilabel(app.MovementPanel);
            app.PositionmmEditFieldLabel.Text = 'Position / mm';
            app.PositionmmEditField = uieditfield(app.MovementPanel, 'numeric');
            app.PositionmmEditField.Limits = [0 290];
            app.PositionmmEditField.ValueDisplayFormat = '%11.2f';
            app.PositionmmEditField.Value = 150;
            app.TypeofMovementButtonGroup = uibuttongroup(app.MovementPanel);
            app.TypeofMovementButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeofMovementButtonGroupSelectionChanged, true);
            app.TypeofMovementButtonGroup.Title = 'Type of Movement';
            app.absoluteButton = uiradiobutton(app.TypeofMovementButtonGroup);
            app.absoluteButton.Text = 'absolute';
            app.absoluteButton.Value = true;
            app.relativeButton = uiradiobutton(app.TypeofMovementButtonGroup);
            app.relativeButton.Enable = 'off';
            app.relativeButton.Text = 'relative';
            app.MOVEButton = uibutton(app.MovementPanel, 'push');
            app.MOVEButton.ButtonPushedFcn = createCallbackFcn(app, @MOVEButtonPushed, true);
            app.MOVEButton.Enable = 'off';
            app.MOVEButton.Text = 'MOVE';
            app.ReferenceRunButton = uibutton(app.MovementPanel, 'push');
            app.ReferenceRunButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceRunButtonPushed, true);
            app.ReferenceRunButton.Enable = 'off';
            app.ReferenceRunButton.Text = 'Reference Run';
            app.referencedLampLabel = uilabel(app.MovementPanel);
            app.referencedLampLabel.Text = 'referenced';
            app.referencedLamp = uilamp(app.MovementPanel);
            app.referencedLamp.Enable = 'off';
            app.PosGauge = uigauge(app.BeamprofilerUIFigure, 'linear');
            app.PosGauge.Limits = [0 300];
            app.PosReadout = uieditfield(app.BeamprofilerUIFigure, 'numeric');
            app.PosReadout.Limits = [0 290];
            app.PosReadout.ValueDisplayFormat = '%11.2f mm';
            app.PosReadout.Editable = 'off';
            app.CameraControlPanel = uipanel(app.BeamprofilerUIFigure);
            app.CameraControlPanel.Title = 'Camera Control';
            app.LiveViewButton = uibutton(app.CameraControlPanel, 'state');
            app.LiveViewButton.ValueChangedFcn = createCallbackFcn(app, @LiveViewButtonValueChanged, true);
            app.LiveViewButton.Text = 'Live View';
            app.CloseCameraButton = uibutton(app.CameraControlPanel, 'push');
            app.CloseCameraButton.ButtonPushedFcn = createCallbackFcn(app, @CloseCameraButtonPushed, true);
            app.CloseCameraButton.Text = 'Close Camera';
            app.TakeImageButton = uibutton(app.CameraControlPanel, 'push');
            app.TakeImageButton.ButtonPushedFcn = createCallbackFcn(app, @TakeImageButtonPushed, true);
            app.TakeImageButton.Enable = 'off';
            app.TakeImageButton.Text = 'Take Image';
            app.exposuretimemsSliderLabel = uilabel(app.CameraControlPanel);
            app.exposuretimemsSliderLabel.Enable = 'off';
            app.exposuretimemsSliderLabel.Text = 'exposure time / ms';
            app.exposuretimemsSlider = uislider(app.CameraControlPanel);
            app.exposuretimemsSlider.Limits = [0.6675 205];
            app.exposuretimemsSlider.MajorTicks = [0.6675 50 100 150 200];
            app.exposuretimemsSlider.MajorTickLabels = {'min', '50', '100', '150', '200'};
            app.exposuretimemsSlider.ValueChangedFcn = createCallbackFcn(app, @exposuretimemsSliderValueChanged, true);
            app.exposuretimemsSlider.MinorTicks = [25 75 125 175];
            app.exposuretimemsSlider.Enable = 'off';
            app.exposuretimemsSlider.Value = 205;
            app.framerateSliderLabel = uilabel(app.CameraControlPanel);
            app.framerateSliderLabel.Enable = 'off';
            app.framerateSliderLabel.Text = 'framerate';
            app.framerateSlider = uislider(app.CameraControlPanel);
            app.framerateSlider.Limits = [4.87 15];
            app.framerateSlider.MajorTicks = [5 10 15];
            app.framerateSlider.ValueChangedFcn = createCallbackFcn(app, @framerateSliderValueChanged, true);
            app.framerateSlider.MinorTicks = [6 7 8 9 11 12 13 14];
            app.framerateSlider.Enable = 'off';
            app.framerateSlider.Value = 4.87;
            app.gainSliderLabel = uilabel(app.CameraControlPanel);
            app.gainSliderLabel.Enable = 'off';
            app.gainSliderLabel.Text = 'gain';
            app.gainSlider = uislider(app.CameraControlPanel);
            app.gainSlider.Limits = [1 10];
            app.gainSlider.MajorTicks = [1 5 10];
            app.gainSlider.ValueChangedFcn = createCallbackFcn(app, @gainSliderValueChanged, true);
            app.gainSlider.MinorTicks = [2 3 4 6 7 8 9];
            app.gainSlider.Enable = 'off';
            app.gainSlider.Value = 1;
            app.ProfileView = uipanel(app.BeamprofilerUIFigure);
            app.ProfileView.Title = 'Profile View';
            app.Profile1 = uiaxes(app.ProfileView);
            title(app.Profile1, 'Profile in x direction')
            xlabel(app.Profile1, 'X / \mum')
            ylabel(app.Profile1, 'I / a.u.')
            app.Profile1.XLim = [0 6656];
            app.Profile1.YLim = [0 255];
            app.Profile1.ZLim = [0 255];
            app.Profile2 = uiaxes(app.ProfileView);
            title(app.Profile2, 'Profile in y direction')
            xlabel(app.Profile2, 'Y / \mum')
            ylabel(app.Profile2, 'I / a.u.')
            app.Profile2.XLim = [0 5325];
            app.Profile2.YLim = [0 255];
            app.Profile2.ZLim = [0 255];
            app.w0_xLabel = uilabel(app.ProfileView);
            app.w0_xLabel.Text = 'w0_x';
            app.w1EditField = uieditfield(app.ProfileView, 'numeric');
            app.w1EditField.ValueDisplayFormat = '%11.1f µm';
            app.w0_yLabel = uilabel(app.ProfileView);
            app.w0_yLabel.Text = 'w0_y';
            app.w2EditField = uieditfield(app.ProfileView, 'numeric');
            app.w2EditField.ValueDisplayFormat = '%11.1f µm';
            app.showfitCheckBox = uicheckbox(app.ProfileView);
            app.showfitCheckBox.Enable = 'off';
            app.showfitCheckBox.Visible = 'off';
            app.showfitCheckBox.Text = 'show fit';
            app.PerformGaussianFitButton = uibutton(app.ProfileView, 'push');
            app.PerformGaussianFitButton.ButtonPushedFcn = createCallbackFcn(app, @PerformGaussianFitButtonPushed, true);
            app.PerformGaussianFitButton.Enable = 'off';
            app.PerformGaussianFitButton.Text = 'Perform Gaussian Fit';
            app.CameraPositionLabel = uilabel(app.BeamprofilerUIFigure);
            app.CameraPositionLabel.Text = 'Camera Position';
            % Show the figure after all components are created
            app.BeamprofilerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeamProfiler2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BeamprofilerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BeamprofilerUIFigure)
        end
    end
end